classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        PlotButton              matlab.ui.control.Button
        SolverButtonGroup       matlab.ui.container.ButtonGroup
        ImplicitButton          matlab.ui.control.ToggleButton
        ExplicitButton          matlab.ui.control.ToggleButton
        StepSizeEditFieldLabel  matlab.ui.control.Label
        StepSizeEditField       matlab.ui.control.NumericEditField
        Switch                  matlab.ui.control.Switch
        ClearButton             matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StepSizeEditField.Value=0.002;
        end

        % Selection changed function: SolverButtonGroup
        function SolverButtonGroupSelectionChanged(app, event)
            
%             eTypeButton = app.SolverButtonGroup.SelectedObject;
%             eType=eTypeButton.Text;
%             valuEditor=app.StepSizeEditField;
%             if eType=="Implicit"
%                 valuEditor.Visible=false;
%             else
%                 valuEditor.Visible=true;
%  
%             end
            
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)


            isLock=app.Switch.Value;
            
            
            eTypeButton = app.SolverButtonGroup.SelectedObject;
            eType=eTypeButton.Text;
            
            
          %calling Explicit or Implicit algorithm depending on the
          %Selection
          h = app.StepSizeEditField.Value;
          app.PlotButton.Enable=false;
          app.PlotButton.Text="Please Wait";
          
            if eType=="Explicit"
                
 
                [x,t]=explicitEular(h);
            else
                [x,t]=ImplicitEular(h);
 
            end
            
            
            %%Ploting the results
            
            if isLock=="Lock"
                holdPlot="on";
            else
                holdPlot="off";
                
            end 
            
            
            hold(app.UIAxes,holdPlot)
            plot(app.UIAxes,t,x)
            app.PlotButton.Enable=true;
            app.PlotButton.Text="Plot";
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
         cla(app.UIAxes,'reset')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 't sec')
            ylabel(app.UIAxes, 'X3')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SolverButtonGroup = uibuttongroup(app.UIFigure);
            app.SolverButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SolverButtonGroupSelectionChanged, true);
            app.SolverButtonGroup.Title = 'Solver';
            app.ImplicitButton = uitogglebutton(app.SolverButtonGroup);
            app.ImplicitButton.Text = 'Implicit';
            app.ImplicitButton.Value = true;
            app.ExplicitButton = uitogglebutton(app.SolverButtonGroup);
            app.ExplicitButton.Text = 'Explicit';
            app.StepSizeEditFieldLabel = uilabel(app.UIFigure);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Lock', 'Unlock'};
            app.Switch.Value = 'Unlock';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end